classdef MICE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UITable                  matlab.ui.control.Table
        UITable2                 matlab.ui.control.Table
        DataPathEditField        matlab.ui.control.EditField
        Label_9                  matlab.ui.control.Label
        PredictedValueEditField  matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        FrequencySpinner         matlab.ui.control.Spinner
        Label_3                  matlab.ui.control.Label
        xxmonthsoldSpinner       matlab.ui.control.Spinner
        Label_2                  matlab.ui.control.Label
        OrganDropDown            matlab.ui.control.DropDown
        Label                    matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        RMSELabel                matlab.ui.control.Label
        RMSEEditField            matlab.ui.control.NumericEditField
        R2Label                  matlab.ui.control.Label
        KLabel                   matlab.ui.control.Label
        HzLabel                  matlab.ui.control.Label
        MonthLabel               matlab.ui.control.Label
        FittingButton            matlab.ui.control.Button
        Label_8                  matlab.ui.control.Label
        MathematicalModelLabel   matlab.ui.control.Label
        DetailedParameterLabel   matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FittingButton
        function FittingButtonPushed(app, event)
            cla(app.UIAxes); % 'reset' 会重置所有属性，包括标题、标签等
            % 如果只想清除图形对象而不重置坐标轴属性，可以使用 cla(app.UIAxes);
       
            % 清空表格 (UITable)
            app.UITable.Data = table(); % 将数据设置为一个空表格
            app.UITable2.Data = table(); % 将数据设置为一个空表格
            %app.UITable.ColumnName= {'R1_A','R1_B','R2_A','R2_B','Q_A','Q_B','a_A','a_B'};
            %app.UITable.ColumnWidth = {45,45,45,45,45,45,45,45};
            % 清空编辑字段
            app.PredictedValueEditField.Value = 0;      % 清空计算阻抗值显示
            app.RMSEEditField.Value = 0;  % 清空 RMSE 显示
            app.EditField_3.Value = 0;    % 清空 R方 显示
            % 创建一个左对齐的样式
            s = uistyle('HorizontalAlignment', 'left');
            % 将该样式应用到整个表格的数据区域
            addStyle(app.UITable, s, 'table', '');
            addStyle(app.UITable2, s, 'table', '');
            [r_squared,rmse_value,T] = fitColeModel_APP(app,processBodyPartData_APP(app.DataPathEditField.Value,app.OrganDropDown.Value));
            app.UITable.Data = {T.Rs_a,T.Rs_b,T.Rct_a,T.Rct_b}; 
            app.UITable2.Data = {T.Q_a,T.Q_b,T.n_a,T.n_b};
            app.PredictedValueEditField.Value = calculate_Z_magnitud_APP(T.Rs_a,T.Rs_b,T.Rct_a,T.Rct_b,T.Q_a,T.Q_b,T.n_a,T.n_b,app.xxmonthsoldSpinner.Value,app.FrequencySpinner.Value);
            app.RMSEEditField.Value = rmse_value;
            app.EditField_3.Value = r_squared;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Impedance modulus -  fitting curve')
            xlabel(app.UIAxes, 'Age-month(month)')
            ylabel(app.UIAxes, 'Frequency(Hz)')
            zlabel(app.UIAxes, 'Impedance(KΩ)')
            app.UIAxes.YScale = 'log';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.DetailedParameterLabel = uilabel(app.UIFigure);
            app.DetailedParameterLabel.Interpreter = 'tex';
            app.DetailedParameterLabel.Text = 'Detailed Parameter';
            app.MathematicalModelLabel = uilabel(app.UIFigure);
            app.MathematicalModelLabel.Interpreter = 'tex';
            app.MathematicalModelLabel.Text = 'Mathematical Model：';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Interpreter = 'latex';
            app.Label_8.Text = {'$$|Z| = \left|R_{\text{1}} + \frac{R_{\text{2}}}{1+Q(j\omega)^{\alpha}}\right|$$'; '$$R_{\text{1}} = R1_A \cdot \text{month} + R1_B$$'; '$$R_{\text{2}} = R2_A \cdot \text{month} + R2_B$$'; '$$Q = Q_A \cdot \text{month} + Q_B$$'; '$$\alpha = a_A \cdot \text{month} + a_B$$'};
            app.FittingButton = uibutton(app.UIFigure, 'push');
            app.FittingButton.ButtonPushedFcn = createCallbackFcn(app, @FittingButtonPushed, true);
            app.FittingButton.Text = 'Fitting';
            app.MonthLabel = uilabel(app.UIFigure);
            app.MonthLabel.Text = 'Month';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'KΩ';
            app.R2Label = uilabel(app.UIFigure);
            app.R2Label.Interpreter = 'latex';
            app.R2Label.Text = '$R^2$';
            app.RMSEEditField = uieditfield(app.UIFigure, 'numeric');
            app.RMSELabel = uilabel(app.UIFigure);
            app.RMSELabel.Interpreter = 'latex';
            app.RMSELabel.Text = 'RMSE';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Interpreter = 'tex';
            app.Label.Text = 'Organ';
            app.OrganDropDown = uidropdown(app.UIFigure);
            app.OrganDropDown.Items = {'Heart', 'Liver', 'Spleen', 'Lung', 'Kidney', 'Muscle', 'Brain', 'Fat', 'Skin'};
            app.OrganDropDown.Value = 'Heart';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Interpreter = 'tex';
            app.Label_2.Text = 'xx-months-old';
            app.xxmonthsoldSpinner = uispinner(app.UIFigure);
            app.xxmonthsoldSpinner.Limits = [2 18];
            app.xxmonthsoldSpinner.Value = 2;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Interpreter = 'tex';
            app.Label_3.Text = 'Frequency';
            app.FrequencySpinner = uispinner(app.UIFigure);
            app.FrequencySpinner.Limits = [20 10000000];
            app.FrequencySpinner.Value = 20;
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Interpreter = 'tex';
            app.Label_4.Text = 'Predicted Value';
            app.PredictedValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Interpreter = 'tex';
            app.Label_9.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.Value = 'D:\EIS';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Q_A'; 'Q_B'; 'α_A'; 'α_B'};
            app.UITable2.RowName = {};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'R1_A'; 'R1_B'; 'R2_A'; 'R2_B'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MICE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end